classdef fffpa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FFFPAUIFigure                  matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MainTab                        matlab.ui.container.Tab
        InputPanel                     matlab.ui.container.Panel
        SelectfilesButton              matlab.ui.control.Button
        startsEditFieldLabel           matlab.ui.control.Label
        startsEditField                matlab.ui.control.NumericEditField
        stopsEditFieldLabel            matlab.ui.control.Label
        stopsEditField                 matlab.ui.control.NumericEditField
        CurrentstepLabel               matlab.ui.control.Label
        FirststeppALabel               matlab.ui.control.Label
        FirststeppAEditField           matlab.ui.control.NumericEditField
        IncrementpALabel               matlab.ui.control.Label
        IncrementpAEditField           matlab.ui.control.NumericEditField
        InjectedcurrentButtonGroup     matlab.ui.container.ButtonGroup
        DedicatedchannelButton         matlab.ui.control.RadioButton
        SetmanuallyButton              matlab.ui.control.RadioButton
        samplingrateHzEditFieldLabel   matlab.ui.control.Label
        samplingrateHzEditField        matlab.ui.control.NumericEditField
        AnalysisPanel                  matlab.ui.container.Panel
        SweepstoprocessListBoxLabel    matlab.ui.control.Label
        SweepstoprocessListBox         matlab.ui.control.ListBox
        OthersweepsDropDownLabel       matlab.ui.control.Label
        OthersweepsDropDown            matlab.ui.control.DropDown
        FirstsweepDropDownLabel        matlab.ui.control.Label
        FirstsweepDropDown             matlab.ui.control.DropDown
        IntergrateoverspikesLabel      matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        OutputPanel                    matlab.ui.container.Panel
        filenameEditFieldLabel         matlab.ui.control.Label
        filenameEditField              matlab.ui.control.EditField
        ExportallCheckBox              matlab.ui.control.CheckBox
        FormatButtonGroup              matlab.ui.container.ButtonGroup
        xlsButton                      matlab.ui.control.RadioButton
        csvButton                      matlab.ui.control.RadioButton
        matButton                      matlab.ui.control.RadioButton
        DataviewerTab                  matlab.ui.container.Tab
        EditButton                     matlab.ui.control.Button
        NextButton                     matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        cellIDSpinnerLabel             matlab.ui.control.Label
        cellIDSpinner                  matlab.ui.control.Spinner
        sweepSpinnerLabel              matlab.ui.control.Label
        sweepSpinner                   matlab.ui.control.Spinner
        SavetooutputCheckBox           matlab.ui.control.CheckBox
        AdvancedTab                    matlab.ui.container.Tab
        DetectADPDropDownLabel         matlab.ui.control.Label
        DetectADPDropDown              matlab.ui.control.DropDown
        VoltagechangemVmsEditFieldLabel  matlab.ui.control.Label
        VoltagechangemVmsEditField     matlab.ui.control.NumericEditField
        SmoothingparametersamplesEditFieldLabel  matlab.ui.control.Label
        SmoothingparametersamplesEditField  matlab.ui.control.NumericEditField
        APthresholddetectionLabel      matlab.ui.control.Label
        maxISIwithinaspiketrainmsEditFieldLabel  matlab.ui.control.Label
        maxISIwithinaspiketrainmsEditField  matlab.ui.control.NumericEditField
        maximumAPdurarionmsEditFieldLabel  matlab.ui.control.Label
        maximumAPdurarionmsEditField   matlab.ui.control.NumericEditField
        maxADPLatencymsEditFieldLabel  matlab.ui.control.Label
        maxADPLatencymsEditField       matlab.ui.control.NumericEditField
        ADPdetectionLabel              matlab.ui.control.Label
        ExtractedfeaturesPanel         matlab.ui.container.Panel
        RheobaseCheckBox               matlab.ui.control.CheckBox
        RMPCheckBox                    matlab.ui.control.CheckBox
        SagdpCheckBox                  matlab.ui.control.CheckBox
        RinputCheckBox                 matlab.ui.control.CheckBox
        ThresholdVCheckBox             matlab.ui.control.CheckBox
        TimeconstantCheckBox           matlab.ui.control.CheckBox
        HalfwidthCheckBox              matlab.ui.control.CheckBox
        DurationCheckBox               matlab.ui.control.CheckBox
        APrisingtimeCheckBox           matlab.ui.control.CheckBox
        APdecaytimeCheckBox            matlab.ui.control.CheckBox
        AHPampCheckBox                 matlab.ui.control.CheckBox
        CellfeaturesLabel              matlab.ui.control.Label
        SpikefeaturesLabel             matlab.ui.control.Label
        APcountCheckBox                matlab.ui.control.CheckBox
        SweepfeaturesLabel             matlab.ui.control.Label
        stAPlatencyCheckBox            matlab.ui.control.CheckBox
        InitialfrequencyCheckBox       matlab.ui.control.CheckBox
        FreqatsaturationCheckBox       matlab.ui.control.CheckBox
        AdapataionratioCheckBox        matlab.ui.control.CheckBox
        RMPstdCheckBox                 matlab.ui.control.CheckBox
        ADPamplitudeCheckBox           matlab.ui.control.CheckBox
        ADPlatencyCheckBox             matlab.ui.control.CheckBox
        APamplitudeCheckBox            matlab.ui.control.CheckBox
        InjectedcurrentstepCheckBox    matlab.ui.control.CheckBox
        IgnorespikesatcurrentonsetCheckBox  matlab.ui.control.CheckBox
        InputscalingEditFieldLabel     matlab.ui.control.Label
        InputscalingEditField          matlab.ui.control.NumericEditField
        SaveselectedButton             matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        %t_scale = 10 % millisecond to sample multiplier
        workfiles = {} % filenames
        path = '' % path to workfiles
        sweeps_flag = {};
        export = '*.xls';
        traces = cell(1);
        cells = cell(1);
        p1 = [];
        %opt = struct();
        cell_header = {};        
        sweep_header =  {};            
        spike_header =   {};
        inspected_cell = 1;
        inspected_sweep = 1;
        totalsweeps = [];
        drawn_cell = {};
        sweep = {};
        out_filename = 'output_file';
        status = {};

        XLim = [0,1];
        opt = struct('current_start', 0.01, 'current_stop', 1.01, 'fs', 10000, ...
                     'spike_fun', '12', 'rb_window', 200, ...
                     'not_a_train', 75, 't_scale', 10, ...
                     'volt_change_thres', 10, 'apr_thres', 5, ...
                     'max_dep_rep', 4, 'last_adp', 75, ...
                     'inj_curr0', 0, 'inj_curr_incr', 0, ...
                     'inj_curr_channel', 1, ...
                     'default_export', 1, 'detect_adp', '1', ...
                     'reject_early_spikes', 1, 'input_scaler', 1);
                      %'sweeps_flag', {''}, ...
    end
       

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %disp('FFFPA statring...')
            app.inspected_cell = 1;
            app.inspected_sweep = 1;
            app.drawn_cell = {};
            app.sweep = {};
            app.traces = cell(1);
            app.cells = cell(1);
            app.p1 = [];
            
            app.startsEditField.Value = app.opt.current_start;              
            app.stopsEditField.Value =  app.opt.current_stop;               
            
            app.samplingrateHzEditField.Value = app.opt.fs;
            
            app.SweepstoprocessListBox.Value = {};
           
            
            app.FirststeppAEditField.Value = app.opt.inj_curr0; 
            app.IncrementpAEditField.Value = app.opt.inj_curr_incr;
            app.OthersweepsDropDown.Value = app.opt.spike_fun(2);
            app.FirstsweepDropDown.Value = app.opt.spike_fun(1);
            app.DetectADPDropDown.Value = app.opt.detect_adp;
            app.VoltagechangemVmsEditField.Value = app.opt.volt_change_thres;
            app.SmoothingparametersamplesEditField.Value = 5;
            app.maxISIwithinaspiketrainmsEditField.Value = app.opt.not_a_train;
            app.maximumAPdurarionmsEditField.Value = app.opt.max_dep_rep; 
            app.maxADPLatencymsEditField.Value = app.opt.last_adp;
            app.InputscalingEditField.Value = app.opt.input_scaler;
            
            app.InjectedcurrentButtonGroup.SelectedObject = app.DedicatedchannelButton;
            app.RheobaseCheckBox.Value = 1;
            app.RMPCheckBox.Value = 1;
            app.SagdpCheckBox.Value = 1;              
            app.RinputCheckBox.Value = 1;                 
            app.ThresholdVCheckBox.Value = 1;             
            app.TimeconstantCheckBox.Value = 1;           
            app.HalfwidthCheckBox.Value = 1;              
            app.DurationCheckBox.Value = 1;               
            app.APrisingtimeCheckBox.Value = 1;           
            app.APdecaytimeCheckBox.Value = 1;            
            app.AHPampCheckBox.Value = 1;                 
            app.APcountCheckBox.Value = 1;                
            app.stAPlatencyCheckBox.Value = 1;            
            app.InitialfrequencyCheckBox.Value = 1;       
            app.FreqatsaturationCheckBox.Value = 1;       
            app.AdapataionratioCheckBox.Value = 1;        
            app.RMPstdCheckBox.Value = 1;                 
            app.ADPamplitudeCheckBox.Value = 1;           
            app.ADPlatencyCheckBox.Value = 1;             
            app.APamplitudeCheckBox.Value = 1;            
            app.InjectedcurrentstepCheckBox.Value = 1;    
            app.IgnorespikesatcurrentonsetCheckBox.Value = 1;
            
            app.DedicatedchannelButton.Value = app.opt.inj_curr_channel;         
            app.SetmanuallyButton.Value = 1 - app.opt.inj_curr_channel;   
            app.FirststeppAEditField.Value = app.opt.inj_curr0;            
            app.IncrementpAEditField.Value = app.opt.inj_curr_incr;
            %app.IncrementpAEditField.Enable = 'off';
            %app.FirststeppAEditField.Enable = 'off';
            %app.IncrementpALabel.Enable = 'off';
            %app.FirststeppALabel.Enable = 'off';
            
            app.cell_header = {'filename', 'rheobase_c', 'tau0',  ...
                               'first_AP','max_APS', 'R_input', ...
                               'rmp', 'rmp_std', 'sag_dp'}; %%'sweeps_of_interest',
                 
            app.sweep_header = {'current_step','count', 'first_spike_lat','adp_amp', 'adp_lat', ...
                                'fmax_init', 'fmax_ss', 'adaptation_ratio'};                    
                
            app.spike_header = {'ap_decay_time', 'ap_rising_time', 'ap_duration', ...
                                'ap_hw', 'th_amp', 'ahp_amp', 'ap_amplitude'};%: [2×1 double]
        end

        % Button pushed function: SelectfilesButton
        function SelectfilesButtonPushed(app, event)
            [app.workfiles, app.path] = uigetfile('*.abf', 'MultiSelect','on');
            if class(app.workfiles) == 'char'
                app.workfiles = {app.workfiles};
            end
            if exist([app.path, 'fffpa_config.mat'], 'file') == 2
                app.opt = load([app.path, 'fffpa_config.mat']);
                disp('FFFPA config found in the data folder, restoring previous parameters')
                app.opt.sweeps_flag = {};
                app.startupFcn();
            end
            addpath(app.path);
            disp('Loading:')
            disp(app.workfiles')
            
            figure(app.FFFPAUIFigure);
        end

        % Value changed function: startsEditField
        function startsEditFieldValueChanged(app, event)
            app.opt.current_start = app.startsEditField.Value;
        end

        % Value changed function: stopsEditField
        function stopsEditFieldValueChanged(app, event)
            app.opt.current_stop = app.stopsEditField.Value;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Process all cells
            if isempty(app.workfiles)
                uialert(app.FFFPAUIFigure,'No input files selected', 'Input missing');
                return
            end
            
            if isempty(app.sweeps_flag)
                uialert(app.FFFPAUIFigure,'Select sweeps to process', 'Input missing');
                return
            end
       
            app.XLim = [(app.opt.current_start-0.1).*app.opt.fs , (app.opt.current_stop+0.1).*app.opt.fs];
            set(app.UIAxes, 'XLim', app.XLim);
            
            opt_backup = app.opt;
            save([app.path, 'fffpa_config.mat'], '-struct', 'opt_backup');
            app.opt.sweeps_flag = app.sweeps_flag;
            disp(['saving to', app.path, 'fffpa_config.mat'])
            app.totalsweeps = ones(length(app.workfiles),1);
            for f = 1:length(app.workfiles)
                %%Get all the data
               [app.cells{f}, app.traces{f}] = APanalysis(app.workfiles{f}, app.opt);
               app.totalsweeps(f) = length(app.cells{f}.sweeps);
            end
            
            app.drawn_cell = app.cells{1};
            sw_ind = app.drawn_cell.sweeps_of_interest(1);
            app.sweep = app.drawn_cell.sweeps(1);
            app.SavetooutputCheckBox.Value = app.drawn_cell.exported_sweeps(1);
            trace = app.traces{1}(:,1, sw_ind);
            plot_trace(app.UIAxes, trace, app.sweep);
            title([app.cells{1}.filename,', sweep:',num2str(sw_ind)], 'Parent', app.UIAxes);
            drawnow;
            app.TabGroup.SelectedTab = app.DataviewerTab;
           
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            %plot trace in a new window
            h_edit = figure('WindowState','maximized');
            dcm = datacursormode(h_edit);
            ax = axes('Parent', h_edit, 'Position', [0.15, 0.2, 0.775, 0.70]);
            sw_ind = app.drawn_cell.sweeps_of_interest(app.inspected_sweep);
            trace = app.traces{app.inspected_cell}(:,1, sw_ind);
            ap_times = [app.sweep.peak_lat, app.sweep.th_lat, app.sweep.ahp_lat];
            ap_values = [app.sweep.peak_volt, app.sweep.th_amp, app.sweep.ahp_v];
            set(h_edit,'WindowKeyPressFcn', @keyPressCallback);
            plot_trace(ax, trace, app.sweep);
            set(ax, 'XLim', app.XLim);
            edit_dot();
            update_plot(ax);
            set(ax, 'XLim', app.XLim);
            instr = annotation('textbox', [0.15, 0.15, 0.5, 0], 'string', 'Z: edit', 'Parent', h_edit,...
                    'EdgeColor', 'none', 'FitBoxToText','on','FontSize', 14);
            instr2 = annotation('textbox', [0.55, 0.15, 0.5, 0], 'string', 'X: save / close', 'Parent', h_edit,...
                    'EdgeColor', 'none', 'FitBoxToText','on','FontSize', 14);
                   
            function keyPressCallback(~, event)
                switch event.Key
                    case 'x'
                        disp('X')
                        app.cells{app.inspected_cell}.sweeps(app.inspected_sweep).peak_lat = ap_times(:,1);
                        app.cells{app.inspected_cell}.sweeps(app.inspected_sweep).th_lat = ap_times(:,2);
                        app.cells{app.inspected_cell}.sweeps(app.inspected_sweep).ahp_lat = ap_times(:,3);
                        app.cells{app.inspected_cell}.sweeps(app.inspected_sweep).peak_volt = ap_values(:,1);
                        app.cells{app.inspected_cell}.sweeps(app.inspected_sweep).th_amp = ap_values(:,2);
                        app.cells{app.inspected_cell}.sweeps(app.inspected_sweep).ahp_v = ap_values(:,3);
                        close(h_edit);
                        app.inspected_cell = app.cellIDSpinner.Value;
                        app.drawn_cell = app.cells{app.inspected_cell};
                        app.sweep = app.drawn_cell.sweeps(app.inspected_sweep);
                        sw_ind = app.drawn_cell.sweeps_of_interest(app.inspected_sweep);
                        trace = app.traces{app.inspected_cell}(:,1, sw_ind);
                        plot_trace(app.UIAxes, trace, app.sweep);
                        title([app.drawn_cell.filename,', sweep:',num2str(sw_ind)], 'Parent', app.UIAxes);
                        drawnow;
                        %update_plot(app.UIAxes);
                        drawnow;
          
                    case 'z'
                        %disp('Z')
                        edit_dot()
                        %update the plot
                        update_plot(ax);
                        %response = 0;
                end
                
            end
        
            function edit_dot()
                T = annotation('textbox', [0.4, 0.85, 0.5, 0], 'string', 'Select object to edit', 'Parent', h_edit,...
                    'EdgeColor', 'none', 'FitBoxToText','on', 'FontSize', 14);
                %T = text(ax, app.current_stop./2,70, );
                %disp('Edit mode ON')
                updates = zeros(2);
                edit_messages = {'Select object to edit', 'Select new position'};
                % select n number of data points using the data cursor - in this case 15
                for i = 1:2
                  set(T, 'string', edit_messages{i});
                  drawnow;
                  %disp(edit_messages{i})
                  set(dcm, 'UpdateFcn', @myupdatefcn, 'SnapToDataVertex', 'on', 'DisplayStyle', 'window', 'Enable', 'on'); 
                  key = waitforbuttonpress;
                  if key == 0
                    c_info = getCursorInfo(dcm);
                    updates(i,:) = c_info.Position;
                  elseif key == 1
                      figure(h_edit);
                      set(T, 'string', '');
                      set(dcm, 'Enable', 'off')
                      return
                  end
                end
                set(dcm, 'Enable', 'off');
                set(T, 'string', '');
                [x,y] = find(ap_times == updates(1,1));
                disp(['Changing value from ', num2str(ap_values(x,y)), ' to ', num2str(updates(2,2))])
                disp(['Changing time from ', num2str(ap_times(x,y)), ' to ', num2str(updates(2,1))])
                ap_values(x,y) = updates(2,2);
                ap_times(x,y) = updates(2,1);
            end
        
            function update_plot(ax)
                tags = {'Peak', 'Thres', 'AHP'};
                %handles = {}
                hold(ax, 'off')
                for jj = 1:size(ap_times,2)
                    h = findobj(h_edit, 'Tag', tags{jj});
                    set(h, 'XData', ap_times(:,jj), 'YData', ap_values(:,jj))
                    drawnow
                    hold(ax, 'on')
                end
            end
        
            function txt = myupdatefcn(~,event)
                
                pos = get(event,'Position');
                dts = get(event.Target,'Tag');
                txt = {dts,...
                       ['X: ',num2str(pos(1))],...
                     ['Y: ',num2str(pos(2))]};
                
            end                  
%                 end
%             end
%             disp(['Changing value from ', num2str(updates(1,2)), ' to ', num2str(updates(2,2))])
%             disp(['Changing time from ', num2str(updates(1,1)), ' to ', num2str(updates(2,1))])

        end

        % Value changed function: cellIDSpinner
        function cellIDSpinnerValueChanged(app, event)
            if app.cellIDSpinner.Value <= length(app.workfiles)
                app.inspected_cell = app.cellIDSpinner.Value;
                app.inspected_sweep = 1;
                app.sweepSpinner.Value = 1;
                app.drawn_cell = app.cells{app.inspected_cell};
                app.sweep = app.drawn_cell.sweeps(app.inspected_sweep);
                sw_ind = app.drawn_cell.sweeps_of_interest(app.inspected_sweep);
                trace = app.traces{app.inspected_cell}(:,1, sw_ind);
                plot_trace(app.UIAxes, trace, app.sweep);
                title([app.drawn_cell.filename,', sweep:',num2str(sw_ind)], 'Parent', app.UIAxes);
                drawnow;
                app.SavetooutputCheckBox.Value = app.drawn_cell.exported_sweeps(app.inspected_sweep);
            else
                app.cellIDSpinner.Value = length(app.workfiles);
                app.inspected_cell = length(app.workfiles);
            end
            
        end

        % Value changed function: sweepSpinner
        function sweepSpinnerValueChanged(app, event)
            if app.sweepSpinner.Value <= app.totalsweeps(app.inspected_cell)
                app.inspected_sweep = app.sweepSpinner.Value;
                app.drawn_cell = app.cells{app.inspected_cell};
                app.sweep = app.drawn_cell.sweeps(app.inspected_sweep);
                sw_ind = app.drawn_cell.sweeps_of_interest(app.inspected_sweep);
                trace = app.traces{app.inspected_cell}(:,1, sw_ind);
                plot_trace(app.UIAxes, trace, app.sweep);
                title([app.cells{app.inspected_cell}.filename,', sweep:',num2str(sw_ind)], 'Parent', app.UIAxes);
                app.SavetooutputCheckBox.Value = app.drawn_cell.exported_sweeps(app.inspected_sweep);
                drawnow;
            else
                app.sweepSpinner.Value = app.totalsweeps(app.inspected_cell);
                app.inspected_sweep = app.totalsweeps(app.inspected_cell);
            end
        end

        % Selection changed function: FormatButtonGroup
        function FormatButtonGroupSelectionChanged(app, event)
            selectedButton = app.FormatButtonGroup.SelectedObject;
            app.export = selectedButton.Text;
            %disp(app.export)
            
        end

        % Button pushed function: SaveselectedButton
        function SaveselectedButtonPushed(app, event)
           filename = [app.path, app.out_filename, app.export(2:end)];
           disp(['saving as ', filename])
           %Check that number of exoprted sweeps is equal for all cells
           n_exported_sweeps = zeros(length(app.cells),1); 
           for c = 1 : length(app.cells)
               n_exported_sweeps(c) = sum(app.cells{c}.exported_sweeps);
           end
           %disp([app.workfiles', n_exported_sweeps'])
           if length(unique(n_exported_sweeps)) ~= 1 && contains(['*.xls', '*.csv'], app.export) == 1
               ind = find(n_exported_sweeps~=median(n_exported_sweeps));
               uialert(app.FFFPAUIFigure, ['To export as .xls or .csv all cells must have equal number of exported sweeps. Check cell(s) ', sprintf('%d ', ind)], 'Unequal number of sweeps');
               return
           elseif contains(['*.xls', '*.csv'], app.export) == 1
               %% EXPORT
               
               cell_id = cell(length(app.workfiles),1);
               for f = 1: length(app.workfiles)
                    app.cells{f} = compute_features(app.cells{f}, app.traces{f}, app.opt);
                    [header, results] = prepare_sweep_results(app.cells{f}, app.sweep_header, app.spike_header, app.opt);
                        [cell_id{f}, nc_header, cell_data] = prepare_cell_data(app.cells{f}, app.cell_header);                          
                        if f == 1
                            %disp(size(header))
                            %disp(size(nc_header))
                            var_names = [nc_header, header];
                            data = zeros(length(app.workfiles), length(var_names));
                        end
                        data(f,:) = [cell_data, results'];   
                    %end
               end
               if strcmp(app.export,'*.xls') == 1 %&& contains(app.sweeps_flag, 'all') ~=1
                   cell_id_xl = cell_id;
                   for i = 1:length(cell_id)
                        cell_id_xl{i} = ['cell_',cell_id{i}];
                   end
                   T = array2table(data', 'RowNames', var_names, ...
                                    'VariableNames', cell_id_xl);
                   disp('Saving XLS')
                   writetable(T, filename, 'Sheet', date, 'Range', ...
                           'A1', 'WriteVariableNames', true,...
                            'WriteRowNames', true);
               elseif strcmp(app.export,'*.csv') == 1 %&& contains(app.sweeps_flag, 'all') ~=1
                   csv_header = cell2mat(join(var_names,','));
                   fid = fopen(filename, 'a') ;
                   format_string = [repmat('%f,',1,length(var_names)+1),'\n'];
                   for z = 1:length(cell_id)
                       if z == 1
                            fprintf(fid, '%s \n', ['cell_id,', csv_header]);
                       end
                       %for i = 1:length(header)
                       d = [str2double(cell_id{z}), data(z,:)];
                       fprintf(fid, format_string, d);    
                   end
                   fclose(fid) ;
               end
           else
               %filename = [datadir, date,'*.mat'];
               cells = app.cells;
               save(filename, 'cells');
               
           end
        end

        % Value changed function: filenameEditField
        function filenameEditFieldValueChanged(app, event)
            app.out_filename = app.filenameEditField.Value;
            
        end

        % Value changed function: FirstsweepDropDown
        function FirstsweepDropDownValueChanged(app, event)
            app.opt.spike_fun(1) = app.FirstsweepDropDown.Value;
            
        end

        % Value changed function: OthersweepsDropDown
        function OthersweepsDropDownValueChanged(app, event)
            app.opt.spike_fun(2) = app.OthersweepsDropDown.Value;
            
        end

        % Value changed function: SweepstoprocessListBox
        function SweepstoprocessListBoxValueChanged(app, event)
            value = app.SweepstoprocessListBox.Value;
            %app.sweeps_flag = join(value, '-');
            app.sweeps_flag = value;
            %disp(class(app.opt.sweeps_flag))
            disp(app.sweeps_flag)
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.inspected_sweep < length(app.drawn_cell.sweeps)
                app.sweepSpinner.Value = app.inspected_sweep + 1;
                sweepSpinnerValueChanged(app, event);
            elseif app.inspected_cell < length(app.cells)
                app.inspected_sweep = 1;
                app.sweepSpinner.Value = 1;
                app.cellIDSpinner.Value = app.inspected_cell + 1;
                cellIDSpinnerValueChanged(app, event);
            end
        end

        % Callback function
        function AdvancedsettingsButtonPushed(app, event)
            app.TabGroup.SelectedTab(app.TabGroup(3));
        end

        % Selection changed function: InjectedcurrentButtonGroup
        function InjectedcurrentButtonGroupSelectionChanged(app, event)
            selectedButton = app.InjectedcurrentButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Set manually') == 1 
                app.IncrementpAEditField.Enable = 'on';
                app.FirststeppAEditField.Enable = 'on';
                app.IncrementpALabel.Enable = 'on';
                app.FirststeppALabel.Enable = 'on';
                app.opt.inj_curr_channel = 0;
                drawnow;
            else
                app.IncrementpAEditField.Enable = 'off';
                app.FirststeppAEditField.Enable = 'off';
                app.IncrementpALabel.Enable = 'off';
                app.FirststeppALabel.Enable = 'off';
                app.opt.inj_curr_channel = 1;
                drawnow;
            end
            
        end

        % Value changed function: FirststeppAEditField
        function FirststeppAEditFieldValueChanged(app, event)
            app.opt.inj_curr0 = app.FirststeppAEditField.Value;
        end

        % Value changed function: IncrementpAEditField
        function IncrementpAEditFieldValueChanged(app, event)
            app.opt.inj_curr_incr = app.IncrementpAEditField.Value;
            
        end

        % Value changed function: ExportallCheckBox
        function ExportallCheckBoxValueChanged(app, event)
            app.opt.default_export = app.ExportallCheckBox.Value;
            
        end

        % Value changed function: SavetooutputCheckBox
        function SavetooutputCheckBoxValueChanged(app, event)
            value = app.SavetooutputCheckBox.Value;
            app.cells{app.inspected_cell}.exported_sweeps(app.inspected_sweep) = value;
            
        end

        % Value changed function: TimeconstantCheckBox
        function TimeconstantCheckBoxValueChanged(app, event)
            value = app.TimeconstantCheckBox.Value;
            if value == 1
                app.cell_header{end+1} = 'tau0';
            else
                app.cell_header(strcmp(app.cell_header,'tau0')) = [];
            end
        end

        % Value changed function: RheobaseCheckBox
        function RheobaseCheckBoxValueChanged(app, event)
            value = app.RheobaseCheckBox.Value;
            if value == 1
                app.cell_header{end+1} = 'rheobase_c';
            else
                app.cell_header(strcmp(app.cell_header,'rheobase_c')) = [];
            end
            %app.cell_header
        end

        % Value changed function: RinputCheckBox
        function RinputCheckBoxValueChanged(app, event)
            value = app.RinputCheckBox.Value;
            if value == 1
                app.cell_header{end+1} = 'R_input';
            else
                app.cell_header(strcmp(app.cell_header,'R_input')) = [];
            end
            %app.cell_header
        end

        % Value changed function: RMPCheckBox
        function RMPCheckBoxValueChanged(app, event)
            value = app.RMPCheckBox.Value;
            if value == 1
                app.cell_header{end+1} = 'rmp';
            else
                app.cell_header(strcmp(app.cell_header,'rmp')) = [];
            end
        end

        % Value changed function: SagdpCheckBox
        function SagdpCheckBoxValueChanged(app, event)
            value = app.SagdpCheckBox.Value;
            if value == 1
                app.cell_header{end+1} = 'sag_dp';
            else
                app.cell_header(strcmp(app.cell_header,'sag_dp')) = [];
            end
        end

        % Value changed function: RMPstdCheckBox
        function RMPstdCheckBoxValueChanged(app, event)
            value = app.RMPstdCheckBox.Value;
            if value == 1
                app.cell_header{end+1} = 'rmp_std';
            else
                app.cell_header(strcmp(app.cell_header,'rmp_std')) = [];
            end
        end

        % Value changed function: APcountCheckBox
        function APcountCheckBoxValueChanged(app, event)
            value = app.APcountCheckBox.Value;
            if value == 1
                app.sweep_header{end+1} = 'count';
            else
                app.sweep_header(strcmp(app.sweep_header,'count')) = [];
            end
          %  app.sweep_header
        end

        % Value changed function: stAPlatencyCheckBox
        function stAPlatencyCheckBoxValueChanged(app, event)
            value = app.stAPlatencyCheckBox.Value;
            if value == 1
                app.sweep_header{end+1} = 'first_spike_lat';
            else
                app.sweep_header(strcmp(app.sweep_header,'first_spike_lat')) = [];
            end
        end

        % Value changed function: ADPamplitudeCheckBox
        function ADPamplitudeCheckBoxValueChanged(app, event)
            value = app.ADPamplitudeCheckBox.Value;
            if value == 1
                app.sweep_header{end+1} = 'adp_amp';
            else
                app.sweep_header(strcmp(app.sweep_header,'adp_amp')) = [];
            end            
        end

        % Value changed function: ADPlatencyCheckBox
        function ADPlatencyCheckBoxValueChanged(app, event)
            value = app.ADPlatencyCheckBox.Value;
            if value == 1
                app.sweep_header{end+1} = 'adp_lat';
            else
                app.sweep_header(strcmp(app.sweep_header,'adp_lat')) = [];
            end            
        end

        % Value changed function: InitialfrequencyCheckBox
        function InitialfrequencyCheckBoxValueChanged(app, event)
            value = app.InitialfrequencyCheckBox.Value;
            if value == 1
                app.sweep_header{end+1} = 'fmax_init';
            else
                app.sweep_header(strcmp(app.sweep_header,'fmax_init')) = [];
            end 
        end

        % Value changed function: FreqatsaturationCheckBox
        function FreqatsaturationCheckBoxValueChanged(app, event)
            value = app.FreqatsaturationCheckBox.Value;
            if value == 1
                app.sweep_header{end+1} = 'fmax_ss';
            else
                app.sweep_header(strcmp(app.sweep_header,'fmax_ss')) = [];
            end 
        end

        % Value changed function: AdapataionratioCheckBox
        function AdapataionratioCheckBoxValueChanged(app, event)
            value = app.AdapataionratioCheckBox.Value;
            if value == 1
                app.sweep_header{end+1} = 'adaptation_ratio';
            else
                app.sweep_header(strcmp(app.sweep_header,'adaptation_ratio')) = [];
            end 
        end

        % Value changed function: APamplitudeCheckBox
        function APamplitudeCheckBoxValueChanged(app, event)
            value = app.APamplitudeCheckBox.Value;
            if value == 1
                app.spike_header{end+1} = 'ap_amplitude';
            else
                app.spike_header(strcmp(app.spike_header,'ap_amplitude')) = [];
            end 
            app.spike_header
        end

        % Value changed function: HalfwidthCheckBox
        function HalfwidthCheckBoxValueChanged(app, event)
            value = app.HalfwidthCheckBox.Value;
            if value == 1
                app.spike_header{end+1} = 'ap_hw';
            else
                app.spike_header(strcmp(app.spike_header,'ap_hw')) = [];
            end 
        end

        % Value changed function: APrisingtimeCheckBox
        function APrisingtimeCheckBoxValueChanged(app, event)
            value = app.APrisingtimeCheckBox.Value;
            if value == 1
                app.spike_header{end+1} = 'ap_rising_time';
            else
                app.spike_header(strcmp(app.spike_header,'ap_rising_time')) = [];
            end 
        end

        % Value changed function: APdecaytimeCheckBox
        function APdecaytimeCheckBoxValueChanged(app, event)
            value = app.APdecaytimeCheckBox.Value;
            if value == 1
                app.spike_header{end+1} = 'ap_decay_time';
            else
                app.spike_header(strcmp(app.spike_header,'ap_decay_time')) = [];
            end 
        end

        % Value changed function: ThresholdVCheckBox
        function ThresholdVCheckBoxValueChanged(app, event)
            value = app.ThresholdVCheckBox.Value;
            if value == 1
                app.spike_header{end+1} = 'th_amp';
            else
                app.spike_header(strcmp(app.spike_header,'th_amp')) = [];
            end 
        end

        % Value changed function: DurationCheckBox
        function DurationCheckBoxValueChanged(app, event)
            value = app.DurationCheckBox.Value;
            if value == 1
                app.spike_header{end+1} = 'ap_duration';
            else
                app.spike_header(strcmp(app.spike_header,'ap_duration')) = [];
            end 
        end

        % Value changed function: AHPampCheckBox
        function AHPampCheckBoxValueChanged(app, event)
            value = app.AHPampCheckBox.Value;
            if value == 1
                app.spike_header{end+1} = 'ahp_amp';
            else
                app.spike_header(strcmp(app.spike_header,'ahp_amp')) = [];
            end 
        end

        % Value changed function: maxISIwithinaspiketrainmsEditField
        function maxISIwithinaspiketrainmsEditFieldValueChanged(app, event)
            app.opt.not_a_train = app.maxISIwithinaspiketrainmsEditField.Value;  
        end

        % Value changed function: maxADPLatencymsEditField
        function maxADPLatencymsEditFieldValueChanged(app, event)
            app.opt.last_adp = app.maxADPLatencymsEditField.Value;
            
        end

        % Value changed function: VoltagechangemVmsEditField
        function VoltagechangemVmsEditFieldValueChanged(app, event)
            app.opt.volt_change_thres = app.VoltagechangemVmsEditField.Value;
            
        end

        % Value changed function: SmoothingparametersamplesEditField
        function SmoothingparametersamplesEditFieldValueChanged(app, event)
            app.opt.apr_thres = app.SmoothingparametersamplesEditField.Value;
            
        end

        % Value changed function: maximumAPdurarionmsEditField
        function maximumAPdurarionmsEditFieldValueChanged(app, event)
            app.opt.max_dep_rep = app.maximumAPdurarionmsEditField.Value;
            
        end

        % Value changed function: DetectADPDropDown
        function DetectADPDropDownValueChanged(app, event)
            app.opt.detect_adp = app.DetectADPDropDown.Value;
            app.opt.detect_adp
            if strcmp(app.opt.detect_adp,'3') == 1
                app.ADPamplitudeCheckBox.Value = 0;
                %ADPlatencyCheckBoxValueChanged(app, event);
                app.ADPlatencyCheckBox.Value = 0;
                drawnow;
            end
                
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.opt = struct('current_start', 0.01, 'current_stop', 1.01, 'fs', 10000, ...
                     'spike_fun', '12', 'rb_window', 200, ...
                     'not_a_train', 75, 't_scale', 10, ...
                     'volt_change_thres', 10, 'apr_thres', 5, ...
                     'max_dep_rep', 4, 'last_adp', 75, ...
                     'inj_curr0', 0, 'inj_curr_incr', 0, ...
                     'inj_curr_channel', 1, ...
                     'default_export', 1, 'detect_adp', '1', ...
                     'reject_early_spikes', 1);
                      %'sweeps_flag', {''}, ...
                      
            app.workfiles = {}; % filenames
            app.startupFcn();
            
            app.sweepSpinner.Value = 1;
            cla(app.UIAxes);
            app.cellIDSpinner.Value = 1;
            disp('Settings reset')
            %disp(app.workfiles)
        end

        % Value changed function: samplingrateHzEditField
        function samplingrateHzEditFieldValueChanged(app, event)
            value = app.samplingrateHzEditField.Value;
            app.opt.t_scale = value./1000.;
        end

        % Value changed function: InjectedcurrentstepCheckBox
        function InjectedcurrentstepCheckBoxValueChanged(app, event)
            value = app.InjectedcurrentstepCheckBox.Value;
            if value == 1
                app.sweep_header{end+1} = 'current_step';
            else
                app.sweep_header(strcmp(app.spike_header,'current_step')) = [];
            end 
            
        end

        % Value changed function: IgnorespikesatcurrentonsetCheckBox
        function IgnorespikesatcurrentonsetCheckBoxValueChanged(app, event)
            value = app.IgnorespikesatcurrentonsetCheckBox.Value;
            if value == 1
                app.opt.reject_early_spikes = 1;
            else
                app.opt.reject_early_spikes = 0;
            end
        end

        % Value changed function: InputscalingEditField
        function InputscalingEditFieldValueChanged(app, event)
            app.opt.input_scaler = app.InputscalingEditField.Value;
   
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FFFPAUIFigure = uifigure;
            app.FFFPAUIFigure.Name = 'FFFPA';
            app.TabGroup = uitabgroup(app.FFFPAUIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.InputPanel = uipanel(app.MainTab);
            app.InputPanel.Title = 'Input';
            app.SelectfilesButton = uibutton(app.InputPanel, 'push');
            app.SelectfilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfilesButtonPushed, true);
            app.SelectfilesButton.Text = 'Select files';
            app.startsEditFieldLabel = uilabel(app.InputPanel);
            app.startsEditFieldLabel.Text = 'start (s)';
            app.startsEditField = uieditfield(app.InputPanel, 'numeric');
            app.startsEditField.ValueChangedFcn = createCallbackFcn(app, @startsEditFieldValueChanged, true);
            app.startsEditField.Value = 0.0812;
            app.stopsEditFieldLabel = uilabel(app.InputPanel);
            app.stopsEditFieldLabel.Text = 'stop (s)';
            app.stopsEditField = uieditfield(app.InputPanel, 'numeric');
            app.stopsEditField.ValueChangedFcn = createCallbackFcn(app, @stopsEditFieldValueChanged, true);
            app.stopsEditField.Value = 0.8812;
            app.CurrentstepLabel = uilabel(app.InputPanel);
            app.CurrentstepLabel.Text = 'Current step';
            app.FirststeppALabel = uilabel(app.InputPanel);
            app.FirststeppALabel.Enable = 'off';
            app.FirststeppALabel.Text = 'First step (pA)';
            app.FirststeppAEditField = uieditfield(app.InputPanel, 'numeric');
            app.FirststeppAEditField.ValueDisplayFormat = '%.0f';
            app.FirststeppAEditField.ValueChangedFcn = createCallbackFcn(app, @FirststeppAEditFieldValueChanged, true);
            app.FirststeppAEditField.Enable = 'off';
            app.IncrementpALabel = uilabel(app.InputPanel);
            app.IncrementpALabel.Enable = 'off';
            app.IncrementpALabel.Text = 'Increment (pA)';
            app.IncrementpAEditField = uieditfield(app.InputPanel, 'numeric');
            app.IncrementpAEditField.ValueDisplayFormat = '%.0f';
            app.IncrementpAEditField.ValueChangedFcn = createCallbackFcn(app, @IncrementpAEditFieldValueChanged, true);
            app.IncrementpAEditField.Enable = 'off';
            app.InjectedcurrentButtonGroup = uibuttongroup(app.InputPanel);
            app.InjectedcurrentButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InjectedcurrentButtonGroupSelectionChanged, true);
            app.InjectedcurrentButtonGroup.Title = 'Injected current';
            app.DedicatedchannelButton = uiradiobutton(app.InjectedcurrentButtonGroup);
            app.DedicatedchannelButton.Text = 'Dedicated channel';
            app.DedicatedchannelButton.Value = true;
            app.SetmanuallyButton = uiradiobutton(app.InjectedcurrentButtonGroup);
            app.SetmanuallyButton.Text = 'Set manually';
            app.samplingrateHzEditFieldLabel = uilabel(app.InputPanel);
            app.samplingrateHzEditFieldLabel.Text = 'sampling rate (Hz)';
            app.samplingrateHzEditField = uieditfield(app.InputPanel, 'numeric');
            app.samplingrateHzEditField.ValueChangedFcn = createCallbackFcn(app, @samplingrateHzEditFieldValueChanged, true);
            app.samplingrateHzEditField.Value = 10000;
            app.AnalysisPanel = uipanel(app.MainTab);
            app.AnalysisPanel.Title = 'Analysis';
            app.SweepstoprocessListBoxLabel = uilabel(app.AnalysisPanel);
            app.SweepstoprocessListBoxLabel.Text = 'Sweeps to process';
            app.SweepstoprocessListBox = uilistbox(app.AnalysisPanel);
            app.SweepstoprocessListBox.Items = {'rheobase', 'saturation', 'all'};
            app.SweepstoprocessListBox.ItemsData = {'rhb', 'sat', 'all'};
            app.SweepstoprocessListBox.Multiselect = 'on';
            app.SweepstoprocessListBox.ValueChangedFcn = createCallbackFcn(app, @SweepstoprocessListBoxValueChanged, true);
            app.SweepstoprocessListBox.Tooltip = {'Select which sweeps to analyse'};
            app.SweepstoprocessListBox.Value = {'rhb'};
            app.OthersweepsDropDownLabel = uilabel(app.AnalysisPanel);
            app.OthersweepsDropDownLabel.Text = 'Other sweeps';
            app.OthersweepsDropDown = uidropdown(app.AnalysisPanel);
            app.OthersweepsDropDown.Items = {'mean', 'median', 'variance', 'first AP'};
            app.OthersweepsDropDown.ItemsData = {'2', '3', '4', '1'};
            app.OthersweepsDropDown.ValueChangedFcn = createCallbackFcn(app, @OthersweepsDropDownValueChanged, true);
            app.OthersweepsDropDown.Value = '2';
            app.FirstsweepDropDownLabel = uilabel(app.AnalysisPanel);
            app.FirstsweepDropDownLabel.Text = 'First sweep';
            app.FirstsweepDropDown = uidropdown(app.AnalysisPanel);
            app.FirstsweepDropDown.Items = {'first AP', 'mean', 'median', 'variance', ''};
            app.FirstsweepDropDown.ItemsData = {'1', '2', '3', '4'};
            app.FirstsweepDropDown.ValueChangedFcn = createCallbackFcn(app, @FirstsweepDropDownValueChanged, true);
            app.FirstsweepDropDown.Value = '1';
            app.IntergrateoverspikesLabel = uilabel(app.AnalysisPanel);
            app.IntergrateoverspikesLabel.Text = 'Intergrate over spikes';
            app.RunButton = uibutton(app.AnalysisPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.OutputPanel = uipanel(app.MainTab);
            app.OutputPanel.Title = 'Output';
            app.filenameEditFieldLabel = uilabel(app.OutputPanel);
            app.filenameEditFieldLabel.Text = 'filename';
            app.filenameEditField = uieditfield(app.OutputPanel, 'text');
            app.filenameEditField.ValueChangedFcn = createCallbackFcn(app, @filenameEditFieldValueChanged, true);
            app.filenameEditField.Value = 'test';
            app.ExportallCheckBox = uicheckbox(app.OutputPanel);
            app.ExportallCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExportallCheckBoxValueChanged, true);
            app.ExportallCheckBox.Text = 'Export all';
            app.ExportallCheckBox.Value = true;
            app.FormatButtonGroup = uibuttongroup(app.MainTab);
            app.FormatButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FormatButtonGroupSelectionChanged, true);
            app.FormatButtonGroup.Title = 'Format';
            app.xlsButton = uiradiobutton(app.FormatButtonGroup);
            app.xlsButton.Text = '*.xls';
            app.xlsButton.Value = true;
            app.csvButton = uiradiobutton(app.FormatButtonGroup);
            app.csvButton.Text = '*.csv';
            app.matButton = uiradiobutton(app.FormatButtonGroup);
            app.matButton.Text = '*.mat';
            app.DataviewerTab = uitab(app.TabGroup);
            app.DataviewerTab.Title = 'Data viewer';
            app.EditButton = uibutton(app.DataviewerTab, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.NextButton = uibutton(app.DataviewerTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.UIAxes = uiaxes(app.DataviewerTab);
            title(app.UIAxes, 'Detected events')
            xlabel(app.UIAxes, 'time (samples)')
            ylabel(app.UIAxes, 'mV')
            app.cellIDSpinnerLabel = uilabel(app.DataviewerTab);
            app.cellIDSpinnerLabel.Text = 'cell ID';
            app.cellIDSpinner = uispinner(app.DataviewerTab);
            app.cellIDSpinner.Limits = [1 Inf];
            app.cellIDSpinner.ValueChangedFcn = createCallbackFcn(app, @cellIDSpinnerValueChanged, true);
            app.cellIDSpinner.Value = 1;
            app.sweepSpinnerLabel = uilabel(app.DataviewerTab);
            app.sweepSpinnerLabel.Text = 'sweep #';
            app.sweepSpinner = uispinner(app.DataviewerTab);
            app.sweepSpinner.Limits = [1 Inf];
            app.sweepSpinner.ValueChangedFcn = createCallbackFcn(app, @sweepSpinnerValueChanged, true);
            app.sweepSpinner.Value = 1;
            app.SavetooutputCheckBox = uicheckbox(app.DataviewerTab);
            app.SavetooutputCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavetooutputCheckBoxValueChanged, true);
            app.SavetooutputCheckBox.Text = 'Save to output';
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.DetectADPDropDownLabel = uilabel(app.AdvancedTab);
            app.DetectADPDropDownLabel.Text = 'Detect ADP';
            app.DetectADPDropDown = uidropdown(app.AdvancedTab);
            app.DetectADPDropDown.Items = {'Rheobase only', 'All sweeps', 'Disabled'};
            app.DetectADPDropDown.ItemsData = {'1', '2', '3'};
            app.DetectADPDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectADPDropDownValueChanged, true);
            app.DetectADPDropDown.Value = '1';
            app.VoltagechangemVmsEditFieldLabel = uilabel(app.AdvancedTab);
            app.VoltagechangemVmsEditFieldLabel.Text = 'Voltage change mV/ms';
            app.VoltagechangemVmsEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.VoltagechangemVmsEditField.ValueChangedFcn = createCallbackFcn(app, @VoltagechangemVmsEditFieldValueChanged, true);
            app.VoltagechangemVmsEditField.Value = 10;
            app.SmoothingparametersamplesEditFieldLabel = uilabel(app.AdvancedTab);
            app.SmoothingparametersamplesEditFieldLabel.Text = 'Smoothing parameter, samples';
            app.SmoothingparametersamplesEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.SmoothingparametersamplesEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothingparametersamplesEditFieldValueChanged, true);
            app.SmoothingparametersamplesEditField.Value = 5;
            app.APthresholddetectionLabel = uilabel(app.AdvancedTab);
            app.APthresholddetectionLabel.Text = 'AP threshold detection';
            app.maxISIwithinaspiketrainmsEditFieldLabel = uilabel(app.AdvancedTab);
            app.maxISIwithinaspiketrainmsEditFieldLabel.Text = ' max ISI within a spike train, ms';
            app.maxISIwithinaspiketrainmsEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.maxISIwithinaspiketrainmsEditField.ValueChangedFcn = createCallbackFcn(app, @maxISIwithinaspiketrainmsEditFieldValueChanged, true);
            app.maxISIwithinaspiketrainmsEditField.Value = 75;
            app.maximumAPdurarionmsEditFieldLabel = uilabel(app.AdvancedTab);
            app.maximumAPdurarionmsEditFieldLabel.Text = 'maximum AP durarion, ms';
            app.maximumAPdurarionmsEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.maximumAPdurarionmsEditField.ValueChangedFcn = createCallbackFcn(app, @maximumAPdurarionmsEditFieldValueChanged, true);
            app.maximumAPdurarionmsEditField.Value = 4;
            app.maxADPLatencymsEditFieldLabel = uilabel(app.AdvancedTab);
            app.maxADPLatencymsEditFieldLabel.Text = 'max ADP Latency ms';
            app.maxADPLatencymsEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.maxADPLatencymsEditField.ValueChangedFcn = createCallbackFcn(app, @maxADPLatencymsEditFieldValueChanged, true);
            app.maxADPLatencymsEditField.Value = 75;
            app.ADPdetectionLabel = uilabel(app.AdvancedTab);
            app.ADPdetectionLabel.Text = 'ADP detection';
            app.ExtractedfeaturesPanel = uipanel(app.AdvancedTab);
            app.ExtractedfeaturesPanel.Title = 'Extracted features';
            app.RheobaseCheckBox = uicheckbox(app.ExtractedfeaturesPanel);
            app.RheobaseCheckBox.ValueChangedFcn = createCallbackFcn(app, @RheobaseCheckBoxValueChanged, true);
            app.RheobaseCheckBox.Text = 'Rheobase';
            app.RheobaseCheckBox.Value = true;
            app.RMPCheckBox = uicheckbox(app.ExtractedfeaturesPanel);
            app.RMPCheckBox.ValueChangedFcn = createCallbackFcn(app, @RMPCheckBoxValueChanged, true);
            app.RMPCheckBox.Text = 'RMP';
            app.RMPCheckBox.Value = true;
            app.SagdpCheckBox = uicheckbox(app.ExtractedfeaturesPanel);
            app.SagdpCheckBox.ValueChangedFcn = createCallbackFcn(app, @SagdpCheckBoxValueChanged, true);
            app.SagdpCheckBox.Text = 'Sag dp';
            app.SagdpCheckBox.Value = true;
            app.RinputCheckBox = uicheckbox(app.ExtractedfeaturesPanel);
            app.RinputCheckBox.ValueChangedFcn = createCallbackFcn(app, @RinputCheckBoxValueChanged, true);
            app.RinputCheckBox.Text = 'R input';
            app.RinputCheckBox.Value = true;
            app.ThresholdVCheckBox = uicheckbox(app.ExtractedfeaturesPanel);
            app.ThresholdVCheckBox.ValueChangedFcn = createCallbackFcn(app, @ThresholdVCheckBoxValueChanged, true);
            app.ThresholdVCheckBox.Text = 'Threshold V';
            app.ThresholdVCheckBox.Value = true;
            app.TimeconstantCheckBox = uicheckbox(app.ExtractedfeaturesPanel);
            app.TimeconstantCheckBox.ValueChangedFcn = createCallbackFcn(app, @TimeconstantCheckBoxValueChanged, true);
            app.TimeconstantCheckBox.Text = 'Time constant';
            app.TimeconstantCheckBox.Value = true;
            app.HalfwidthCheckBox = uicheckbox(app.ExtractedfeaturesPanel);
            app.HalfwidthCheckBox.ValueChangedFcn = createCallbackFcn(app, @HalfwidthCheckBoxValueChanged, true);
            app.HalfwidthCheckBox.Text = 'Half-width';
            app.HalfwidthCheckBox.Value = true;
            app.DurationCheckBox = uicheckbox(app.ExtractedfeaturesPanel);
            app.DurationCheckBox.ValueChangedFcn = createCallbackFcn(app, @DurationCheckBoxValueChanged, true);
            app.DurationCheckBox.Text = 'Duration';
            app.DurationCheckBox.Value = true;
            app.APrisingtimeCheckBox = uicheckbox(app.ExtractedfeaturesPanel);
            app.APrisingtimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @APrisingtimeCheckBoxValueChanged, true);
            app.APrisingtimeCheckBox.Text = 'AP rising time';
            app.APrisingtimeCheckBox.Value = true;
            app.APdecaytimeCheckBox = uicheckbox(app.ExtractedfeaturesPanel);
            app.APdecaytimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @APdecaytimeCheckBoxValueChanged, true);
            app.APdecaytimeCheckBox.Text = 'AP decay time';
            app.APdecaytimeCheckBox.Value = true;
            app.AHPampCheckBox = uicheckbox(app.ExtractedfeaturesPanel);
            app.AHPampCheckBox.ValueChangedFcn = createCallbackFcn(app, @AHPampCheckBoxValueChanged, true);
            app.AHPampCheckBox.Text = 'AHP amp';
            app.AHPampCheckBox.Value = true;
            app.CellfeaturesLabel = uilabel(app.ExtractedfeaturesPanel);
            app.CellfeaturesLabel.Text = 'Cell features';
            app.SpikefeaturesLabel = uilabel(app.ExtractedfeaturesPanel);
            app.SpikefeaturesLabel.Text = 'Spike features';
            app.APcountCheckBox = uicheckbox(app.ExtractedfeaturesPanel);
            app.APcountCheckBox.ValueChangedFcn = createCallbackFcn(app, @APcountCheckBoxValueChanged, true);
            app.APcountCheckBox.Text = 'AP count';
            app.APcountCheckBox.Value = true;
            app.SweepfeaturesLabel = uilabel(app.ExtractedfeaturesPanel);
            app.SweepfeaturesLabel.Text = 'Sweep features';
            app.stAPlatencyCheckBox = uicheckbox(app.ExtractedfeaturesPanel);
            app.stAPlatencyCheckBox.ValueChangedFcn = createCallbackFcn(app, @stAPlatencyCheckBoxValueChanged, true);
            app.stAPlatencyCheckBox.Text = '1st AP latency';
            app.stAPlatencyCheckBox.Value = true;
            app.InitialfrequencyCheckBox = uicheckbox(app.ExtractedfeaturesPanel);
            app.InitialfrequencyCheckBox.ValueChangedFcn = createCallbackFcn(app, @InitialfrequencyCheckBoxValueChanged, true);
            app.InitialfrequencyCheckBox.Text = 'Initial frequency';
            app.InitialfrequencyCheckBox.Value = true;
            app.FreqatsaturationCheckBox = uicheckbox(app.ExtractedfeaturesPanel);
            app.FreqatsaturationCheckBox.ValueChangedFcn = createCallbackFcn(app, @FreqatsaturationCheckBoxValueChanged, true);
            app.FreqatsaturationCheckBox.Text = 'Freq at saturation';
            app.FreqatsaturationCheckBox.Value = true;
            app.AdapataionratioCheckBox = uicheckbox(app.ExtractedfeaturesPanel);
            app.AdapataionratioCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdapataionratioCheckBoxValueChanged, true);
            app.AdapataionratioCheckBox.Text = 'Adapataion ratio';
            app.AdapataionratioCheckBox.Value = true;
            app.RMPstdCheckBox = uicheckbox(app.ExtractedfeaturesPanel);
            app.RMPstdCheckBox.ValueChangedFcn = createCallbackFcn(app, @RMPstdCheckBoxValueChanged, true);
            app.RMPstdCheckBox.Text = 'RMP std';
            app.RMPstdCheckBox.Value = true;
            app.ADPamplitudeCheckBox = uicheckbox(app.ExtractedfeaturesPanel);
            app.ADPamplitudeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ADPamplitudeCheckBoxValueChanged, true);
            app.ADPamplitudeCheckBox.Text = 'ADP amplitude';
            app.ADPamplitudeCheckBox.Value = true;
            app.ADPlatencyCheckBox = uicheckbox(app.ExtractedfeaturesPanel);
            app.ADPlatencyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ADPlatencyCheckBoxValueChanged, true);
            app.ADPlatencyCheckBox.Text = 'ADP latency';
            app.ADPlatencyCheckBox.Value = true;
            app.APamplitudeCheckBox = uicheckbox(app.ExtractedfeaturesPanel);
            app.APamplitudeCheckBox.ValueChangedFcn = createCallbackFcn(app, @APamplitudeCheckBoxValueChanged, true);
            app.APamplitudeCheckBox.Text = 'AP amplitude';
            app.APamplitudeCheckBox.Value = true;
            app.InjectedcurrentstepCheckBox = uicheckbox(app.ExtractedfeaturesPanel);
            app.InjectedcurrentstepCheckBox.ValueChangedFcn = createCallbackFcn(app, @InjectedcurrentstepCheckBoxValueChanged, true);
            app.InjectedcurrentstepCheckBox.Text = 'Injected current step';
            app.InjectedcurrentstepCheckBox.Value = true;
            app.IgnorespikesatcurrentonsetCheckBox = uicheckbox(app.ExtractedfeaturesPanel);
            app.IgnorespikesatcurrentonsetCheckBox.ValueChangedFcn = createCallbackFcn(app, @IgnorespikesatcurrentonsetCheckBoxValueChanged, true);
            app.IgnorespikesatcurrentonsetCheckBox.Text = 'Ignore spikes at current onset';
            app.IgnorespikesatcurrentonsetCheckBox.Value = true;
            app.InputscalingEditFieldLabel = uilabel(app.ExtractedfeaturesPanel);
            app.InputscalingEditFieldLabel.Text = 'Input scaling';
            app.InputscalingEditField = uieditfield(app.ExtractedfeaturesPanel, 'numeric');
            app.InputscalingEditField.ValueChangedFcn = createCallbackFcn(app, @InputscalingEditFieldValueChanged, true);
            app.InputscalingEditField.Value = 1;
            app.SaveselectedButton = uibutton(app.FFFPAUIFigure, 'push');
            app.SaveselectedButton.ButtonPushedFcn = createCallbackFcn(app, @SaveselectedButtonPushed, true);
            app.SaveselectedButton.Text = 'Save selected';
            app.ResetButton = uibutton(app.FFFPAUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
        end
    end

    methods (Access = public)

        % Construct app
        function app = fffpa

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FFFPAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FFFPAUIFigure)
        end
    end
end